classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        Circle               matlab.ui.container.Tab
        PickColorButton      matlab.ui.control.Button
        CreateShapeButton    matlab.ui.control.Button
        RadiusSlider         matlab.ui.control.Slider
        RadiusSliderLabel    matlab.ui.control.Label
        Rectangle            matlab.ui.container.Tab
        CreateShapeButton_2  matlab.ui.control.Button
        PickColorButton_2    matlab.ui.control.Button
        WidthSlider          matlab.ui.control.Slider
        WidthSliderLabel     matlab.ui.control.Label
        LengthSlider         matlab.ui.control.Slider
        LengthSliderLabel    matlab.ui.control.Label
        Triangle             matlab.ui.container.Tab
        WidthSliderLabel_2   matlab.ui.control.Label
        LengthSliderLabel_2  matlab.ui.control.Label
        CreateShapeButton_3  matlab.ui.control.Button
        PickColorButton_3    matlab.ui.control.Button
        HeightSlider         matlab.ui.control.Slider
        BaseSlider           matlab.ui.control.Slider
        EqTriangle           matlab.ui.container.Tab
        LengthSliderLabel_3  matlab.ui.control.Label
        CreateShapeButton_4  matlab.ui.control.Button
        PickColorButton_4    matlab.ui.control.Button
        BaseSlider_2         matlab.ui.control.Slider
    end


    properties (Access = private)
        radius
        color
        length
        width
        base
        height
        sidelen
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RadiusSlider
        function RadiusSliderValueChanged(app, event)
            app.radius = app.RadiusSlider.Value;
        end

        % Button pushed function: PickColorButton
        function PickColorButtonPushed(app, event)
            app.color = uisetcolor;
        end

        % Button pushed function: CreateShapeButton
        function CreateShapeButtonPushed(app, event)
            shape = Circle(app.radius, app.color);
            clf
            shape.Draw();
        end

        % Value changing function: RadiusSlider
        function RadiusSliderValueChanging(app, event)
            app.radius = event.Value;
        end

        % Value changed function: LengthSlider
        function LengthSliderValueChanged(app, event)
            app.length = app.LengthSlider.Value;
            
        end

        % Value changed function: WidthSlider
        function WidthSliderValueChanged(app, event)
            app.width = app.WidthSlider.Value;
            
        end

        % Button pushed function: PickColorButton_2
        function PickColorButton_2Pushed(app, event)
            app.color = uisetcolor;

        end

        % Button pushed function: CreateShapeButton_2
        function CreateShapeButton_2Pushed(app, event)
            shape = Rectangle(app.length, app.width, app.color);
            clf
            shape.Draw();
        end

        % Value changed function: BaseSlider
        function BaseSliderValueChanged(app, event)
            app.base = app.BaseSlider.Value;
            
        end

        % Value changed function: HeightSlider
        function HeightSliderValueChanged(app, event)
            app.height = app.HeightSlider.Value;
            
        end

        % Button pushed function: PickColorButton_3
        function PickColorButton_3Pushed(app, event)
            app.color = uisetcolor;
        end

        % Button pushed function: CreateShapeButton_3
        function CreateShapeButton_3Pushed(app, event)
            shape = Triangle(app.base, app.height, app.color);
            clf
            shape.Draw();
        end

        % Value changed function: BaseSlider_2
        function BaseSlider_2ValueChanged(app, event)
            app.sidelen = app.BaseSlider_2.Value;
            
        end

        % Button pushed function: CreateShapeButton_4
        function CreateShapeButton_4Pushed(app, event)
            shape = EquiTriangle(app.sidelen, app.color);
            clf
            shape.Draw();
        end

        % Button pushed function: PickColorButton_4
        function PickColorButton_4Pushed(app, event)
            app.color = uisetcolor;
        end

        % Value changing function: BaseSlider_2
        function BaseSlider_2ValueChanging(app, event)
            app.sidelen = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Circle = uitab(app.TabGroup);
            app.Circle.Title = 'Circle';
            app.RadiusSliderLabel = uilabel(app.Circle);
            app.RadiusSliderLabel.Text = 'Radius';
            app.RadiusSlider = uislider(app.Circle);
            app.RadiusSlider.MajorTicks = [10 20 30 40 50 60 70 80 90 100];
            app.RadiusSlider.ValueChangedFcn = createCallbackFcn(app, @RadiusSliderValueChanged, true);
            app.RadiusSlider.ValueChangingFcn = createCallbackFcn(app, @RadiusSliderValueChanging, true);
            app.CreateShapeButton = uibutton(app.Circle, 'push');
            app.CreateShapeButton.ButtonPushedFcn = createCallbackFcn(app, @CreateShapeButtonPushed, true);
            app.CreateShapeButton.Text = 'Create Shape';
            app.PickColorButton = uibutton(app.Circle, 'push');
            app.PickColorButton.ButtonPushedFcn = createCallbackFcn(app, @PickColorButtonPushed, true);
            app.PickColorButton.Text = 'Pick Color';
            app.Rectangle = uitab(app.TabGroup);
            app.Rectangle.Title = 'Rectangle';
            app.LengthSliderLabel = uilabel(app.Rectangle);
            app.LengthSliderLabel.Text = 'Length';
            app.LengthSlider = uislider(app.Rectangle);
            app.LengthSlider.MajorTicks = [10 20 30 40 50 60 70 80 90 100];
            app.LengthSlider.ValueChangedFcn = createCallbackFcn(app, @LengthSliderValueChanged, true);
            app.WidthSliderLabel = uilabel(app.Rectangle);
            app.WidthSliderLabel.Text = 'Width';
            app.WidthSlider = uislider(app.Rectangle);
            app.WidthSlider.MajorTicks = [10 20 30 40 50 60 70 80 90 100];
            app.WidthSlider.ValueChangedFcn = createCallbackFcn(app, @WidthSliderValueChanged, true);
            app.PickColorButton_2 = uibutton(app.Rectangle, 'push');
            app.PickColorButton_2.ButtonPushedFcn = createCallbackFcn(app, @PickColorButton_2Pushed, true);
            app.PickColorButton_2.Text = 'Pick Color';
            app.CreateShapeButton_2 = uibutton(app.Rectangle, 'push');
            app.CreateShapeButton_2.ButtonPushedFcn = createCallbackFcn(app, @CreateShapeButton_2Pushed, true);
            app.CreateShapeButton_2.Text = 'Create Shape';
            app.Triangle = uitab(app.TabGroup);
            app.Triangle.Title = 'Tirangle';
            app.BaseSlider = uislider(app.Triangle);
            app.BaseSlider.MajorTicks = [10 20 30 40 50 60 70 80 90 100];
            app.BaseSlider.ValueChangedFcn = createCallbackFcn(app, @BaseSliderValueChanged, true);
            app.HeightSlider = uislider(app.Triangle);
            app.HeightSlider.MajorTicks = [10 20 30 40 50 60 70 80 90 100];
            app.HeightSlider.ValueChangedFcn = createCallbackFcn(app, @HeightSliderValueChanged, true);
            app.PickColorButton_3 = uibutton(app.Triangle, 'push');
            app.PickColorButton_3.ButtonPushedFcn = createCallbackFcn(app, @PickColorButton_3Pushed, true);
            app.PickColorButton_3.Text = 'Pick Color';
            app.CreateShapeButton_3 = uibutton(app.Triangle, 'push');
            app.CreateShapeButton_3.ButtonPushedFcn = createCallbackFcn(app, @CreateShapeButton_3Pushed, true);
            app.CreateShapeButton_3.Text = 'Create Shape';
            app.LengthSliderLabel_2 = uilabel(app.Triangle);
            app.LengthSliderLabel_2.Text = 'Base';
            app.WidthSliderLabel_2 = uilabel(app.Triangle);
            app.WidthSliderLabel_2.Text = 'Height';
            app.EqTriangle = uitab(app.TabGroup);
            app.EqTriangle.Title = 'EqTriangle';
            app.BaseSlider_2 = uislider(app.EqTriangle);
            app.BaseSlider_2.MajorTicks = [10 20 30 40 50 60 70 80 90 100];
            app.BaseSlider_2.ValueChangedFcn = createCallbackFcn(app, @BaseSlider_2ValueChanged, true);
            app.BaseSlider_2.ValueChangingFcn = createCallbackFcn(app, @BaseSlider_2ValueChanging, true);
            app.PickColorButton_4 = uibutton(app.EqTriangle, 'push');
            app.PickColorButton_4.ButtonPushedFcn = createCallbackFcn(app, @PickColorButton_4Pushed, true);
            app.PickColorButton_4.Text = 'Pick Color';
            app.CreateShapeButton_4 = uibutton(app.EqTriangle, 'push');
            app.CreateShapeButton_4.ButtonPushedFcn = createCallbackFcn(app, @CreateShapeButton_4Pushed, true);
            app.CreateShapeButton_4.Text = 'Create Shape';
            app.LengthSliderLabel_3 = uilabel(app.EqTriangle);
            app.LengthSliderLabel_3.Text = 'Side Length';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end